classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        StartButton                 matlab.ui.control.Button
        palettesizeEditField        matlab.ui.control.NumericEditField
        palettesizeLabel            matlab.ui.control.Label
        outputwidthEditField        matlab.ui.control.NumericEditField
        outputwidthEditFieldLabel   matlab.ui.control.Label
        outputheightEditField       matlab.ui.control.NumericEditField
        outputheightEditFieldLabel  matlab.ui.control.Label
        SelectimageButton           matlab.ui.control.Button
        UIAxes                      matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SelectimageButton
        function SelectimageButtonPushed(app, event)
            addpath('images');
            [file,~] = uigetfile('*.jpg');
            if(file ~= 0) % make sure a file is selected
            disp(file)
            app.UIAxes.UserData = file;
%             source_filename = 'images/shrek.jpg';
            input_imag = imread(file);
            input_imag = imresize(input_imag, 0.5);
            imshow(input_imag, 'Parent', app.UIAxes);
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            if(isempty(app.UIAxes.UserData))
                disp('No image selected')
            elseif(app.outputwidthEditField.Value < 5 || app.outputheightEditField.Value < 5)
                disp('Output size needs to be at least 6x6')
            elseif(app.palettesizeEditField.Value < 2)
                disp('Palette size needs to be at least 2')
            else
            GeneratePixelArt(app.UIAxes.UserData,app.outputheightEditField.Value,app.outputwidthEditField.Value,app.palettesizeEditField.Value);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Input Image')
            app.SelectimageButton = uibutton(app.UIFigure, 'push');
            app.SelectimageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectimageButtonPushed, true);
            app.SelectimageButton.Text = 'Select image';
            app.outputheightEditFieldLabel = uilabel(app.UIFigure);
            app.outputheightEditFieldLabel.Text = 'output height:';
            app.outputheightEditField = uieditfield(app.UIFigure, 'numeric');
            app.outputwidthEditFieldLabel = uilabel(app.UIFigure);
            app.outputwidthEditFieldLabel.Text = 'output width:';
            app.outputwidthEditField = uieditfield(app.UIFigure, 'numeric');
            app.palettesizeLabel = uilabel(app.UIFigure);
            app.palettesizeLabel.Text = 'palette size:';
            app.palettesizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StartButton = uibutton(app.UIFigure, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end